classdef audio_test < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        tone_duration_box          matlab.ui.control.NumericEditField
        RFFrequencyGHzLabel_4      matlab.ui.control.Label
        audio_sr_box               matlab.ui.control.NumericEditField
        RFFrequencyGHzLabel_3      matlab.ui.control.Label
        audio_freq_box             matlab.ui.control.NumericEditField
        FrequencyHzEditFieldLabel  matlab.ui.control.Label
        play_button                matlab.ui.control.Button
        speed_box                  matlab.ui.control.NumericEditField
        RFFrequencyGHzLabel_2      matlab.ui.control.Label
        rf_freq_box                matlab.ui.control.NumericEditField
        RFFrequencyGHzLabel        matlab.ui.control.Label
    end

    
    properties (Access = private)
        rf_freq;             % Description
        desired_speed;
        audio_freq;
        play_audio = false;
        sample_rate = 44100;
        tone_duration;
        tone;
    end

    
    methods (Access = private)
        
        function audio_freq = calculate_frequency(app)
            
            audio_freq = (app.desired_speed * app.rf_freq * 1e9) / 299792458;

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            parameter_value_change(app, 0);
        end

        % Value changed function: audio_sr_box, rf_freq_box, speed_box, 
        % ...and 1 other component
        function parameter_value_change(app, event)
            app.rf_freq = app.rf_freq_box.Value;
            app.desired_speed = app.speed_box.Value;
            app.sample_rate = app.audio_sr_box.Value;
            app.audio_freq = calculate_frequency(app);

            app.audio_freq_box.Value = app.audio_freq;

            app.tone_duration = app.tone_duration_box.Value;

            tone_samples = floor(app.sample_rate * app.tone_duration);
            t = 0:1:tone_samples-1;
            
            app.tone = cos(2*pi*app.audio_freq/app.sample_rate*t);


        end

        % Button pushed function: play_button
        function play_button_pushed(app, event)
            % app.play_audio = ~app.play_audio;

            audio_obj = audioplayer(app.tone, app.sample_rate);
            % count = 0;
            % while (app.play_audio == true)
            % while(count < 200)
                % tic;
                play(audio_obj);
                pause(app.tone_duration);
                % drawnow;
                % toc;
                % count  =  count + 1;
            % end

            fprintf("audio complete!\n")

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.RFFrequencyGHzLabel = uilabel(app.UIFigure);
            app.RFFrequencyGHzLabel.Text = 'RF Frequency (GHz)';
            app.rf_freq_box = uieditfield(app.UIFigure, 'numeric');
            app.rf_freq_box.Limits = [0 Inf];
            app.rf_freq_box.ValueChangedFcn = createCallbackFcn(app, @parameter_value_change, true);
            app.rf_freq_box.Value = 9;
            app.RFFrequencyGHzLabel_2 = uilabel(app.UIFigure);
            app.RFFrequencyGHzLabel_2.Text = 'Desired Speed (m/s)';
            app.speed_box = uieditfield(app.UIFigure, 'numeric');
            app.speed_box.Limits = [0 Inf];
            app.speed_box.ValueChangedFcn = createCallbackFcn(app, @parameter_value_change, true);
            app.speed_box.Value = 44;
            app.play_button = uibutton(app.UIFigure, 'push');
            app.play_button.ButtonPushedFcn = createCallbackFcn(app, @play_button_pushed, true);
            app.play_button.Text = 'Play';
            app.FrequencyHzEditFieldLabel = uilabel(app.UIFigure);
            app.FrequencyHzEditFieldLabel.Text = 'Frequency (Hz)';
            app.audio_freq_box = uieditfield(app.UIFigure, 'numeric');
            app.audio_freq_box.Limits = [0 Inf];
            app.audio_freq_box.ValueDisplayFormat = '%.0f';
            app.audio_freq_box.Editable = 'off';
            app.RFFrequencyGHzLabel_3 = uilabel(app.UIFigure);
            app.RFFrequencyGHzLabel_3.Text = 'Audio Samplerate (S/s)';
            app.audio_sr_box = uieditfield(app.UIFigure, 'numeric');
            app.audio_sr_box.Limits = [0 Inf];
            app.audio_sr_box.ValueDisplayFormat = '%d';
            app.audio_sr_box.ValueChangedFcn = createCallbackFcn(app, @parameter_value_change, true);
            app.audio_sr_box.Value = 44100;
            app.RFFrequencyGHzLabel_4 = uilabel(app.UIFigure);
            app.RFFrequencyGHzLabel_4.Text = 'Tone Duration (s)';
            app.tone_duration_box = uieditfield(app.UIFigure, 'numeric');
            app.tone_duration_box.Limits = [0 Inf];
            app.tone_duration_box.ValueChangedFcn = createCallbackFcn(app, @parameter_value_change, true);
            app.tone_duration_box.Value = 10;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = audio_test

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end