classdef view_bbox < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        BoundingBoxViewerUIFigure  matlab.ui.Figure
        LoadDataButton             matlab.ui.control.Button
        ImagesListBoxLabel         matlab.ui.control.Label
        image_list_lb              matlab.ui.control.ListBox
        image_ax                   matlab.ui.control.UIAxes
        BoxIndexEditFieldLabel     matlab.ui.control.Label
        box_idx_tb                 matlab.ui.control.NumericEditField
    end


    properties (Access = private)
        data_path = 'D:/Projects'   % Description
        bbox_data                   % Description
        bbox_label
        num_classes = 4;            % Description
        cm 
        class_names                 % Description
    end
       
    %--------------------------------------------------------------------------
    
    methods (Access = private)
        
    %--------------------------------------------------------------------------
        function uifigurepop(app)
            drawnow;
            app.BoundingBoxViewerUIFigure.Visible = 'off';
            app.BoundingBoxViewerUIFigure.Visible = 'on';
        end   
    
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            axis(app.image_ax, 'off');
            app.cm = rand(app.num_classes,3);
            app.class_names = {'c1','c2','c3','c4'};
        end

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
            file_filter = {'*.txt','Text Files';'*.*','All Files' };
            
            
            [input_file, input_path] = uigetfile(file_filter, 'Select Data Input File', app.data_path);
            if(input_path == 0)
                return;
            end
            
            uifigurepop(app);
            
            %app.input_file_tb.Value = input_file;
%             app.save_path = uigetdir('D:\IUPUI\PhD\Images\datasets','Select Save Folder');
%             if(app.save_path == 0)
%                 return;
%             end
            
            %input_data = parse_input_parameters(fullfile(input_path, input_file));
            input_data = parse_grouped_input_paramters(fullfile(input_path, input_file), {'{', '}'});
            
            %app.data_path = strcat(getenv('HOME'), '/', input_data{1}{1});
            app.data_path = input_data{1}{1};
            input_data(1) = [];
            
            tmp = cell(length(input_data),1);
            
            app.bbox_data = cell(length(input_data),1);
            app.bbox_label = cell(length(input_data),1);
            
            start = app.box_idx_tb.Value;

            tmp_labels = {};
            % get the depth map files
            for idx=1:length(input_data)    
                %tmp_file = strsplit(input_data{idx}{3},{'_','.'});
                %tmp{idx,1} = strcat(tmp_file{1},'_',tmp_file{2},'_',tmp_file{3},'_',tmp_file{4},'.bin');
                tmp{idx,1} = input_data{idx}{1};
                
                
                for jdx=start:numel(input_data{idx})
                    
                    tmp_box = parse_line(input_data{idx}{jdx},',');
                    app.bbox_data{idx}{end+1} = [ str2double(tmp_box{1}), str2double(tmp_box{2}), str2double(tmp_box{3}), str2double(tmp_box{4})];
                    app.bbox_label{idx}{end+1} = tmp_box{5};
                    tmp_labels{end+1} = tmp_box{5};
                end
                
            end

            img_files = tmp;
                        
            app.image_list_lb.Items = img_files;
            app.image_list_lb.ItemsData = [1:numel(img_files)];
            
            app.class_names = unique(tmp_labels);            
                      
        end

        % Value changed function: image_list_lb
        function image_list_lbValueChanged(app, event)
            index = app.image_list_lb.Value;
            
            img = imread(fullfile(app.data_path, app.image_list_lb.Items{index}));
            [img_h, img_w, ~] = size(img);
                                
            for idx=1:numel(app.bbox_data{index})
                tmp_label = app.bbox_label{index}{idx};
                
                cm_index = find(contains(app.class_names, tmp_label));
                label_color = floor(255*app.cm(cm_index,:));
                
                img = insertObjectAnnotation(img,'rectangle',app.bbox_data{index}{idx}, tmp_label, 'Color', label_color, 'LineWidth',2, 'TextBoxOpacity',0.9, 'FontSize',floor(0.0123*img_h+7), 'TextColor','black');
                               
            end
            
            image(app.image_ax, img);
            colormap(app.image_ax, gray(256));
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.BoundingBoxViewerUIFigure = uifigure('Visible', 'off');
            app.BoundingBoxViewerUIFigure.Name = 'Bounding Box Viewer';
            app.LoadDataButton = uibutton(app.BoundingBoxViewerUIFigure, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Text = 'Load Data';
            app.ImagesListBoxLabel = uilabel(app.BoundingBoxViewerUIFigure);
            app.ImagesListBoxLabel.Text = 'Images';
            app.image_list_lb = uilistbox(app.BoundingBoxViewerUIFigure);
            app.image_list_lb.Items = {};
            app.image_list_lb.ValueChangedFcn = createCallbackFcn(app, @image_list_lbValueChanged, true);
            app.image_list_lb.Value = {};
            app.image_ax = uiaxes(app.BoundingBoxViewerUIFigure);
            app.BoxIndexEditFieldLabel = uilabel(app.BoundingBoxViewerUIFigure);
            app.BoxIndexEditFieldLabel.Text = 'Box Index';
            app.box_idx_tb = uieditfield(app.BoundingBoxViewerUIFigure, 'numeric');
            app.box_idx_tb.Limits = [2 Inf];
            app.box_idx_tb.RoundFractionalValues = 'on';
            app.box_idx_tb.Value = 2;
            % Show the figure after all components are created
            app.BoundingBoxViewerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = view_bbox

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.BoundingBoxViewerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.BoundingBoxViewerUIFigure)
        end
    end
end