classdef mnist_viewer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        LoadImageButton  matlab.ui.control.Button
        RunNetButton     matlab.ui.control.Button
        img_plot         matlab.ui.control.UIAxes
    end


    properties (Access = private)
        start_path = 'D:\Projects\mnist\data\test'; % Description
        image_file;
        image_path;
        input_img;
        fsw;
        run_net = false;
        layer_struct = struct('k', 0, 'n',0, 'nr', 0, 'nc', 0, 'size',0);
        lib_path = 'D:\Projects\mnist_dll\build_dll\Release\';
        lib_name = 'MNIST_DLL';
        lib_header_file = 'D:\Projects\mnist_dll\include\mnist_dll.h';
    end

    methods (Access = private)
    
        function update_data(app, source, event_arg)
            
            padding = 3;
            map_length = 1000;
        
            % load in the modified image
            app.input_img = rgb2gray(imread(fullfile(app.image_path, app.image_file)));
            
            image(app.img_plot, app.input_img);
            axis(app.img_plot, 'off');
            colormap(app.img_plot, gray(256));
            
            img2 = app.input_img';
            
            net_result = calllib(app.lib_name, 'run_net', img2(:), 28, 28);
            
            % Layer 01
            ld_01 = libpointer('singlePtr', []);
            [ls_01] = calllib(app.lib_name,'get_layer_01', app.layer_struct, ld_01);         
            ld_01.reshape(ls_01.n, ls_01.k);
            
            % Layer 02
            ld_02 = libpointer('singlePtr', []);
            [ls_02] = calllib(app.lib_name,'get_layer_02', app.layer_struct, ld_02);
            ld_02.reshape(ls_02.n,ls_02.k);
            
            % Layer 05
            ld_05 = libpointer('singlePtr', []);
            [ls_05] = calllib(app.lib_name,'get_layer_05', app.layer_struct, ld_05);
            ld_05.reshape(ls_05.n,ls_05.k);
            
            % Layer 12
            ld_12 = libpointer('singlePtr', []);           
            [ls_12] = calllib(app.lib_name,'get_layer_12', app.layer_struct, ld_12);
            ld_12.reshape(ls_12.n,ls_12.size);
            
            cell_dim = [7, 19];           
            [layer_img_12] = build_layer_image(ls_12, ld_12, cell_dim, padding, map_length);


            % Layer 01 - Plotting
            figure(1)
            box on
            grid on
            b1 = bar([0:1:ls_01.k-1], ld_01.Value, 'FaceColor', [0.5, 0.5, 0.5]);
            hold on
            b2 = bar(net_result, ld_01.Value(net_result+1), 'FaceColor','r');
            hold off
            
            % Layer 02 - Plotting
            figure(2)
            box on
            grid on
            b1 = bar([0:1:ls_02.k-1], ld_02.Value, 'FaceColor', 'b');
            
            % Layer 05 - Plotting
            figure(3)
            box on
            grid on
            b1 = bar([0:1:ls_05.k-1], ld_05.Value, 'FaceColor', 'b');


            % Layer 12 - Plotting
            figure(6)
            set(gcf,'position',([50,50,1300,400]),'color','w')
            image(layer_img_12)
            axis off
            ax = gca;
            ax.Position = [0 0 1 1];            
            
            
            pause(0.005);
        end
        
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            loadlibrary(fullfile(app.lib_path, strcat(app.lib_name,'.dll')), app.lib_header_file);
            calllib(app.lib_name,'init_net','D:/Projects/mnist_dll/nets/mnist_net_pso_14_97.dat');
            
            axis(app.img_plot, 'off');
        end

        % Button pushed function: LoadImageButton
        function LoadImageButtonPushed(app, event)

            file_filter = {'*.png','PNG Image Files'; '*.*','All Files' };
            
            [app.image_file, app.image_path] = uigetfile(file_filter, 'Select Input Image File', app.start_path, 'multiselect','off');
            
            app.input_img = rgb2gray(imread(fullfile(app.image_path, app.image_file)));
            
            image(app.img_plot, app.input_img);
            axis(app.img_plot, 'off');
            colormap(app.img_plot, gray(256));


            app.fsw = System.IO.FileSystemWatcher(app.image_path);
            app.fsw.Filter = app.image_file;
            app.fsw.EnableRaisingEvents = false;
            addlistener(app.fsw, 'Changed', @app.update_data);
            
            
        end

        % Button pushed function: RunNetButton
        function RunNetButtonPushed(app, event)

            if(app.run_net)
                app.run_net = false;
                app.RunNetButton.Text = 'Run Net';
                app.RunNetButton.FontColor = 'k';
                app.fsw.EnableRaisingEvents = false;
            else
                app.run_net = true;
                app.RunNetButton.Text = 'Stop Net';
                app.RunNetButton.FontColor = 'r';
                app.fsw.EnableRaisingEvents = true;
            end
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            
            if(libisloaded(app.lib_name))
                unloadlibrary(app.lib_name);
            end
            delete(app)
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.LoadImageButton = uibutton(app.UIFigure, 'push');
            app.LoadImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageButtonPushed, true);
            app.LoadImageButton.Text = 'Load Image';
            app.RunNetButton = uibutton(app.UIFigure, 'push');
            app.RunNetButton.ButtonPushedFcn = createCallbackFcn(app, @RunNetButtonPushed, true);
            app.RunNetButton.Text = 'Run Net';
            app.img_plot = uiaxes(app.UIFigure);
        end
    end

    methods (Access = public)

        % Construct app
        function app = mnist_viewer

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end