classdef tiff_viewer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TIFF_Viewer_Figure   matlab.ui.Figure
        image_view           matlab.ui.control.UIAxes
        ImageSliderLabel     matlab.ui.control.Label
        image_selector       matlab.ui.control.Slider
        load_image_btn       matlab.ui.control.Button
        image_filename_tb    matlab.ui.control.EditField
        save_image_btn       matlab.ui.control.Button
        image_selector_edit  matlab.ui.control.Spinner
    end

    
    properties (Access = private)
        tiff_stack;  % Description
        
        data_path;   % Description
        image_index; % Description
    end
    
    methods (Access = private)
        
    %--------------------------------------------------------------------------
        function uifigurepop(app)
            drawnow;
            app.TIFF_Viewer_Figure.Visible = 'off';
            app.TIFF_Viewer_Figure.Visible = 'on';
        end  
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            full_path = mfilename('fullpath');
            [scriptpath,  filename, ext] = fileparts(full_path);
            app.data_path = scriptpath;
            app.image_index = 1;
            
        end

        % Button pushed function: load_image_btn
        function load_image_btnButtonPushed(app, event)
            file_filter = {'*.tif;*.tiff','Tiff Files';'*.*','All Files' };
            
            
            [input_file, input_path] = uigetfile(file_filter, 'Select Data Input File', app.data_path);
            if(input_path == 0)
                return;
            end
            
            app.data_path = input_path;
            
            uifigurepop(app);
            
            app.image_filename_tb.Value = fullfile(input_path, input_file);
            
            % read in the tiff stack
            app.tiff_stack = read_tiff_stack(fullfile(input_path, input_file), true);
            
            num_images = numel(app.tiff_stack);
            
            app.image_selector.Limits = [1, num_images];
            
            app.image_index = floor(app.image_selector.Value);
            app.image_selector_edit.Value = app.image_index;
            
            image(app.image_view, uint8(app.tiff_stack{app.image_index}));
            colormap(app.image_view, gray(256));
            
        end

        % Callback function: image_selector, image_selector
        function image_selectorValueChanged(app, event)
            app.image_index = floor(app.image_selector.Value);
            app.image_selector_edit.Value = app.image_index;
            
            image(app.image_view, uint8(app.tiff_stack{app.image_index}));
            colormap(app.image_view, gray(256));

        end

        % Button pushed function: save_image_btn
        function save_image_btnButtonPushed(app, event)
            
            [save_path, save_filename, ext] = fileparts(app.image_filename_tb.Value);
            
            if(save_path == 0)
                return;
            end
            
            imwrite(uint8(app.tiff_stack{app.image_index}), strcat(save_path,filesep,save_filename,num2str(app.image_index,'_%05d.png')));
            
        end

        % Value changed function: image_selector_edit
        function image_selector_editValueChanged(app, event)
            app.image_index = floor(app.image_selector_edit.Value);
            app.image_selector.Value = app.image_index;
            
            image(app.image_view, uint8(app.tiff_stack{app.image_index}));
            colormap(app.image_view, gray(256));            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.TIFF_Viewer_Figure = uifigure('Visible', 'off');
            app.TIFF_Viewer_Figure.Name = 'MATLAB App';
            app.image_view = uiaxes(app.TIFF_Viewer_Figure);
            title(app.image_view, '')
            xlabel(app.image_view, '')
            ylabel(app.image_view, '')
            zlabel(app.image_view, '')
            app.image_view.XTick = [];
            app.image_view.XTickLabel = '';
            app.image_view.YTick = [];
            app.image_view.YTickLabel = '';
            app.ImageSliderLabel = uilabel(app.TIFF_Viewer_Figure);
            app.ImageSliderLabel.Text = 'Image #';
            app.image_selector = uislider(app.TIFF_Viewer_Figure);
            app.image_selector.Limits = [1 100];
            app.image_selector.ValueChangedFcn = createCallbackFcn(app, @image_selectorValueChanged, true);
            app.image_selector.ValueChangingFcn = createCallbackFcn(app, @image_selectorValueChanged, true);
            app.image_selector.Value = 1;
            app.load_image_btn = uibutton(app.TIFF_Viewer_Figure, 'push');
            app.load_image_btn.ButtonPushedFcn = createCallbackFcn(app, @load_image_btnButtonPushed, true);
            app.load_image_btn.Text = 'Load Tiff Stack';
            app.image_filename_tb = uieditfield(app.TIFF_Viewer_Figure, 'text');
            app.save_image_btn = uibutton(app.TIFF_Viewer_Figure, 'push');
            app.save_image_btn.ButtonPushedFcn = createCallbackFcn(app, @save_image_btnButtonPushed, true);
            app.save_image_btn.Text = 'Save Image';
            app.image_selector_edit = uispinner(app.TIFF_Viewer_Figure);
            app.image_selector_edit.Limits = [1 Inf];
            app.image_selector_edit.ValueChangedFcn = createCallbackFcn(app, @image_selector_editValueChanged, true);
            app.image_selector_edit.Value = 1;
            % Show the figure after all components are created
            app.TIFF_Viewer_Figure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = tiff_viewer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.TIFF_Viewer_Figure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TIFF_Viewer_Figure)
        end
    end
end