classdef view_net_output < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        slice_plot         matlab.ui.control.UIAxes
        load_btn           matlab.ui.control.Button
        FilterOutputLabel  matlab.ui.control.Label
        slice_spin         matlab.ui.control.Spinner
    end


    properties (Access = private)
        layer_num % Description
        gorgon_data % Description
        start_path = mfilename('fullpath'); % Description
    end

    methods (Access = private)
    
        function update_plot(app, index)
            mn = min(min(app.gorgon_data(:,:,index)));
            mx = max(max(app.gorgon_data(:,:,index)));
            image(app.slice_plot, 255*(app.gorgon_data(:,:,index)-mn)/(mx-mn));
            %slice(app.slice_plot, app.gorgon_data, [], [], index);
            %view(app.slice_plot,0,-60);
            colormap(app.slice_plot,jet(256));
            title(app.slice_plot, strcat('Layer', 32, num2str(app.layer_num), ' - Filter Output:', 32, num2str(index)));
            %shading(app.slice_plot, 'flat');
            %axis(app.slice_plot, 'off');
        end
    %--------------------------------------------------------------------------

        function uifigurepop(app)
            drawnow;
            app.UIFigure.Visible = 'off';
            app.UIFigure.Visible = 'on';
        end 
    
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            axis(app.slice_plot, 'off');
        end

        % Button pushed function: load_btn
        function load_btnButtonPushed(app, event)
            file_filter = {'*.xml','XML Files';'*.*','All Files' };
            [filename, filepath] = uigetfile(file_filter,'Select XML file',app.start_path);
            
            if(filepath==0)
                return;
            end
            
            app.start_path = filepath;
            gorgon_xml_struct = xml2struct(fullfile(filepath, filename));

            if(~isfield(gorgon_xml_struct,'gorgon_data'))
                return;
            end
            
            version = strcat(gorgon_xml_struct.gorgon_data.version.Attributes.major, '.', gorgon_xml_struct.gorgon_data.version.Attributes.minor);
            app.layer_num = str2double(gorgon_xml_struct.gorgon_data.layer.Attributes.number);
            
            nc = str2double(gorgon_xml_struct.gorgon_data.filter.Attributes.cols);
            nr = str2double(gorgon_xml_struct.gorgon_data.filter.Attributes.rows);
            n = str2double(gorgon_xml_struct.gorgon_data.filter.Attributes.n);
            k = str2double(gorgon_xml_struct.gorgon_data.filter.Attributes.k);
            data_size = n*nr*nc*k;
            
            [file_path, datafile_name, ~] = fileparts(fullfile(filepath,filename));

            datafile_name=strcat(file_path,'\',datafile_name,'.dat');
            tmp=dir(datafile_name);
            file_size=tmp.bytes;
            
            fileID = fopen(datafile_name, 'r','l');
            
            magic_number = fread(fileID,1,'uint32');
            
            app.gorgon_data = [];
            
            index=1;
            while ~feof(fileID)
                
                step = fread(fileID,1,'uint64');
                position = ftell(fileID);
                
                if((file_size-position)>=data_size*4)
                    app.gorgon_data(:,:,index) = reshape(fread(fileID,nr*nc,'float32'),nc,nr)';
                    index = index + 1;
                else
                    fseek(fileID, 0,'eof');
                    break;
                end
                
            end
            fclose(fileID);
            
%             app.slice_sld.Limits = [1,size(app.gorgon_data,3)];
%             app.slice_sld.MajorTicks = 1:8:size(app.gorgon_data,3);
%             app.slice_sld.MinorTicks = 1:1:size(app.gorgon_data,3);
            app.slice_spin.Limits = [1,size(app.gorgon_data,3)];
            app.slice_spin.Value = 1;
            
            update_plot(app, 1);
            uifigurepop(app);

        end

        % Callback function
        function slice_sldValueChanged(app, event)
            value = floor(app.slice_sld.Value);
            update_plot(app, value);
        end

        % Value changed function: slice_spin
        function slice_spinValueChanged(app, event)
            value = app.slice_spin.Value;
            update_plot(app, value);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.slice_plot = uiaxes(app.UIFigure);
            title(app.slice_plot, '    ')
            app.load_btn = uibutton(app.UIFigure, 'push');
            app.load_btn.ButtonPushedFcn = createCallbackFcn(app, @load_btnButtonPushed, true);
            app.load_btn.Text = 'Load Data';
            app.FilterOutputLabel = uilabel(app.UIFigure);
            app.FilterOutputLabel.Text = 'Filter Output:';
            app.slice_spin = uispinner(app.UIFigure);
            app.slice_spin.Limits = [1 256];
            app.slice_spin.ValueChangedFcn = createCallbackFcn(app, @slice_spinValueChanged, true);
            app.slice_spin.Value = 1;
        end
    end

    methods (Access = public)

        % Construct app
        function app = view_net_output

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end